classdef T1optimizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MRIviewTab               matlab.ui.container.Tab
        MRI_UIAxes3              matlab.ui.control.UIAxes
        MRI_UIAxes2              matlab.ui.control.UIAxes
        MRI_UIAxes1              matlab.ui.control.UIAxes
        OptimizedT1Tab           matlab.ui.container.Tab
        T1tap_UIAxes             matlab.ui.control.UIAxes
        progressGauge            matlab.ui.control.LinearGauge
        progressGaugeLabel       matlab.ui.control.Label
        MRILoadButton            matlab.ui.control.Button
        T1FileLocationLabel      matlab.ui.control.Label
        SelectfileButton         matlab.ui.control.Button
        SegmentationButton       matlab.ui.control.Button
        MakeameshButton          matlab.ui.control.Button
        PairedcomputationButton  matlab.ui.control.Button
        TIoptimizationButton     matlab.ui.control.Button
    end


    properties (Access = private)
        SelectedNiiFile
        SelectedNiiFileName
        num_el
        reference
        node
        elem
        face
        hdrinfo
        electrode
    end

    properties (Access = public)
        UserData = struct() % Description: UserData at figure
    end


    methods (Access = private)


        %         function myCallback(app, fh,~)
        %             % This selects new position depending on which subplot a button is clicked.
        %             % Updates display information in mydata.
        %
        %             mydata = get(fh,'UserData');
        %             pos    = get(gca,'CurrentPoint');
        %             pos    = round(pos(1,1:2));
        %             switch gca
        %                 case mydata.h(1); mydata.pos([1 3]) = pos;
        %                 case mydata.h(2); mydata.pos([2 3]) = pos;
        %                 case mydata.h(3); mydata.pos([1 2]) = pos;
        %             end
        %
        %             % if new position is valid, update mydata as figure property and display
        %             % otherwise do nothing
        %             if ~sum( mydata.pos<1 | mydata.pos>size(mydata.img) )
        %                 set(fh,'UserData',mydata);
        %                 showimages(app)
        %             end
        %
        %         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)

        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            % select sensor coordinates file
            fileName = uigetfile('*.nii','Select a NIfTI file');
            if fileName ~= 0 % 사용자가 파일을 선택한 경우
                app.SelectedNiiFile = niftiread(fileName);
            end
            app.T1FileLocationLabel.Text = fileName;
            app.SelectedNiiFileName=fileName;

        end

        % Button pushed function: MRILoadButton
        function MRILoadButtonPushed(app, event)
            if isempty(app.SelectedNiiFile)
                errordlg('Please select a NIfTI file first.', 'Error');
                return;
            end
            T1Data = load_untouch_nii(app.SelectedNiiFileName); % call load_untouch_nii func

            sliceshow_T1(app, T1Data.img,[],'gray',[],[]); drawnow
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)

            start_seg(app.SelectedNiiFileName,[]); %% app.SelectedNiiFileName가 있는 폴더에서 실행
            segTouchup(app.SelectedNiiFileName,[]); %% segmentation 된 이미지 보정

        end

        % Button pushed function: MakeameshButton
        function MakeameshButtonPushed(app, event)
            app.electrode = '10_10';

            if app.electrode == '10_10'
                load('81options.mat')
                app.hdrinfo=electrodePlacement(app.SelectedNiiFileName,app.SelectedNiiFileName,[],{'F3';'F4'},options,int2str(1));
                app.num_el=81;
                app.reference=-3;
            else
                load('18options.mat')
                app.hdrinfo=electrodePlacement(app.SelectedNiiFileName,app.SelectedNiiFileName,[],{'F3';'F4'},options,int2str(1));
                app.num_el=18;
                app.reference=-3;
            end

            [app.node,app.elem,app.face] = meshByIso2mesh(app.SelectedNiiFileName,app.SelectedNiiFileName,[],options.meshOpt,app.hdrinfo,int2str(1)); %% mesh 생성
        end

        % Button pushed function: PairedcomputationButton
        function PairedcomputationButtonPushed(app, event)

            prepareForGetDP(app.SelectedNiiFileName,app.node,app.elem,{'F3';'F4'},int2str(1),app.num_el); %% solve하기 전에 하는거
        end

        % Button down function: MRI_UIAxes1
        function MRI_UIAxes1ButtonDown(app, event)
            disp('MRI_UIAxes1 tap pussed');
            
            
        end

        % Button down function: MRI_UIAxes2
        function MRI_UIAxes2ButtonDown(app, event)
            disp('MRI_UIAxes2 tap pussed');
            
            
        end

        % Button down function: MRI_UIAxes3
        function MRI_UIAxes3ButtonDown(app, event)
            disp('MRI_UIAxes3 tap pussed');
            
            
        end

        % Button down function: T1tap_UIAxes
        function T1tap_UIAxesButtonDown(app, event)
            disp('T1tap Axes pussed');
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TIoptimizationButton = uibutton(app.UIFigure, 'push');
            app.TIoptimizationButton.Text = 'TI optimization';
            app.PairedcomputationButton = uibutton(app.UIFigure, 'push');
            app.PairedcomputationButton.ButtonPushedFcn = createCallbackFcn(app, @PairedcomputationButtonPushed, true);
            app.PairedcomputationButton.Text = 'Paired computation';
            app.MakeameshButton = uibutton(app.UIFigure, 'push');
            app.MakeameshButton.ButtonPushedFcn = createCallbackFcn(app, @MakeameshButtonPushed, true);
            app.MakeameshButton.Text = 'Make a mesh';
            app.SegmentationButton = uibutton(app.UIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.T1FileLocationLabel = uilabel(app.UIFigure);
            app.T1FileLocationLabel.Text = 'T1 File Location';
            app.MRILoadButton = uibutton(app.UIFigure, 'push');
            app.MRILoadButton.ButtonPushedFcn = createCallbackFcn(app, @MRILoadButtonPushed, true);
            app.MRILoadButton.Text = 'MRI Load';
            app.progressGaugeLabel = uilabel(app.UIFigure);
            app.progressGaugeLabel.Text = 'progress';
            app.progressGauge = uigauge(app.UIFigure, 'linear');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MRIviewTab = uitab(app.TabGroup);
            app.MRIviewTab.Title = 'MRI view';
            app.MRI_UIAxes1 = uiaxes(app.MRIviewTab);
            app.MRI_UIAxes1.XTick = [];
            app.MRI_UIAxes1.YTick = [];
            app.MRI_UIAxes1.ButtonDownFcn = createCallbackFcn(app, @MRI_UIAxes1ButtonDown, true);
            app.MRI_UIAxes2 = uiaxes(app.MRIviewTab);
            app.MRI_UIAxes2.XTick = [];
            app.MRI_UIAxes2.YTick = [];
            app.MRI_UIAxes2.ButtonDownFcn = createCallbackFcn(app, @MRI_UIAxes2ButtonDown, true);
            app.MRI_UIAxes3 = uiaxes(app.MRIviewTab);
            app.MRI_UIAxes3.XTick = [];
            app.MRI_UIAxes3.YTick = [];
            app.MRI_UIAxes3.ButtonDownFcn = createCallbackFcn(app, @MRI_UIAxes3ButtonDown, true);
            app.OptimizedT1Tab = uitab(app.TabGroup);
            app.OptimizedT1Tab.Title = 'Optimized T1';
            app.T1tap_UIAxes = uiaxes(app.OptimizedT1Tab);
            title(app.T1tap_UIAxes, 'Title')
            xlabel(app.T1tap_UIAxes, 'X')
            ylabel(app.T1tap_UIAxes, 'Y')
            zlabel(app.T1tap_UIAxes, 'Z')
            app.T1tap_UIAxes.ButtonDownFcn = createCallbackFcn(app, @T1tap_UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1optimizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end